classdef PMTSat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GaugeLabel                matlab.ui.control.Label
        Gauge                     matlab.ui.control.SemicircularGauge
        KnobLabel                 matlab.ui.control.Label
        Knob                      matlab.ui.control.Knob
        PMTSaturationLevelLabel   matlab.ui.control.Label
        DCVoltageEditFieldLabel   matlab.ui.control.Label
        DCVoltageEditField        matlab.ui.control.NumericEditField
        VLabel                    matlab.ui.control.Label
        ExtVoltageEditFieldLabel  matlab.ui.control.Label
        ExtVoltageEditField       matlab.ui.control.NumericEditField
        mVRMSLabel                matlab.ui.control.Label
    end


    properties (Access = private)
        t; % Description
    end

    properties (Access = public)
        boardHandle; % Description
    end

    methods (Access = private)
        function updateGauge(app, objTimer, timerEvent )
            rawData = acquireDCLevelCS(app);
            data = convertUnsignedSampleToVolts(rawData, 2);
            RMS = 1e3*sqrt(mean(data.^2 , 1));
            app.Gauge.Value = sum(RMS);
            app.DCVoltageEditField.Value = sum(RMS);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Gauge.ScaleColors = {'yellow','green','red'}; 
            app.Gauge.ScaleColorLimits = [350 555; 555 740; 740 925];
            [app.boardHandle, ~, ~] = initCardToDCLevel(); 
            app.t = timer;
            app.t.Period = 0.2;
            app.t.TimerFcn = {@app.updateGauge};
            app.t.ExecutionMode = 'fixedRate';
            start(app.t) 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.t)
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Gauge.Limits = [350 925];
            app.Gauge.MinorTicks = [];
            app.Gauge.Value = 925;
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.PMTSaturationLevelLabel = uilabel(app.UIFigure);
            app.PMTSaturationLevelLabel.Text = 'PMT Saturation Level';
            app.DCVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.DCVoltageEditFieldLabel.Text = 'DC Voltage';
            app.DCVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = '[V]';
            app.ExtVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.ExtVoltageEditFieldLabel.Text = 'Ext. Voltage';
            app.ExtVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.mVRMSLabel = uilabel(app.UIFigure);
            app.mVRMSLabel.Text = '[mV-RMS]';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PMTSat

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end