classdef debugFigures < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AcquisitionCheckBox     matlab.ui.control.CheckBox
        StagesCheckBox          matlab.ui.control.CheckBox
        AFGCheckBox             matlab.ui.control.CheckBox
        ReshapeCheckBox         matlab.ui.control.CheckBox
        FFTCheckBox             matlab.ui.control.CheckBox
        OkButton                matlab.ui.control.Button
        CancelButton            matlab.ui.control.Button
        PostProcessingCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, currenStatus)
            app.CallingApp = mainApp;
            
            app.AcquisitionCheckBox.Value = currenStatus.AcquisitionFig;
            app.StagesCheckBox.Value = currenStatus.StagesFig;
            app.AFGCheckBox.Value = currenStatus.AFGFig;
            app.ReshapeCheckBox.Value = currenStatus.ReshapeFig;
            app.FFTCheckBox.Value = currenStatus.FFTFig;
            app.PostProcessingCheckBox.Value = currenStatus.PostProcessingFig;
            
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            figsRequest.AcquisitionFig = app.AcquisitionCheckBox.Value;
            figsRequest.StagesFig = app.StagesCheckBox.Value;
            figsRequest.AFGFig = app.AFGCheckBox.Value;
            figsRequest.ReshapeFig = app.ReshapeCheckBox.Value;
            figsRequest.FFTFig = app.FFTCheckBox.Value;
            figsRequest.PostProcessingFig = app.PostProcessingCheckBox.Value;
            app.CallingApp.updateDebugFigureRequest(figsRequest)
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.UIFigureCloseRequest();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AcquisitionCheckBox = uicheckbox(app.UIFigure);
            app.AcquisitionCheckBox.Text = 'Acquisition';
            app.StagesCheckBox = uicheckbox(app.UIFigure);
            app.StagesCheckBox.Text = 'Stages';
            app.AFGCheckBox = uicheckbox(app.UIFigure);
            app.AFGCheckBox.Text = 'AFG';
            app.ReshapeCheckBox = uicheckbox(app.UIFigure);
            app.ReshapeCheckBox.Text = 'Reshape';
            app.FFTCheckBox = uicheckbox(app.UIFigure);
            app.FFTCheckBox.Text = 'FFT';
            app.PostProcessingCheckBox = uicheckbox(app.UIFigure);
            app.PostProcessingCheckBox.Text = 'Post Processing';
        end
    end

    methods (Access = public)

        % Construct app
        function app = debugFigures(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end