classdef peripheralDeactivate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DontMoveStagesCheckBox   matlab.ui.control.CheckBox
        DontOpenIOCheckBox       matlab.ui.control.CheckBox
        DontActivateAFGCheckBox  matlab.ui.control.CheckBox
        OkButton                 matlab.ui.control.Button
        CancelButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, currenStatus)
            app.CallingApp = mainApp;
            app.DontMoveStagesCheckBox.Value = currenStatus.DontMoveStages;
            app.DontOpenIOCheckBox.Value = currenStatus.DontOpenIO;
            app.DontActivateAFGCheckBox.Value = currenStatus.DontActivateAFG;
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            peripherals.DontMoveStages  = app.DontMoveStagesCheckBox.Value;
            peripherals.DontOpenIO      = app.DontOpenIOCheckBox.Value;
            peripherals.DontActivateAFG = app.DontActivateAFGCheckBox.Value;
            app.CallingApp.updateDebugPeripherals(peripherals)
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.UIFigureCloseRequest();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DontMoveStagesCheckBox = uicheckbox(app.UIFigure);
            app.DontMoveStagesCheckBox.Text = 'Don''t Move Stages';
            app.DontOpenIOCheckBox = uicheckbox(app.UIFigure);
            app.DontOpenIOCheckBox.Text = 'Don''t Open IO';
            app.DontActivateAFGCheckBox = uicheckbox(app.UIFigure);
            app.DontActivateAFGCheckBox.Text = 'Don''t Activate AFG';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = peripheralDeactivate(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end