classdef stagesCalib < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StagesControl                   matlab.ui.Figure
        StagesControlAndCalibrateToolLabel  matlab.ui.control.Label
        XaxisstagePanel                 matlab.ui.container.Panel
        GlobalmovementPanel             matlab.ui.container.Panel
        MoveButton                      matlab.ui.control.Button
        MoveModeButtonGroup             matlab.ui.container.ButtonGroup
        RelativisticButton              matlab.ui.control.RadioButton
        AbsoluteButton                  matlab.ui.control.RadioButton
        RelativePositionEditFieldLabel  matlab.ui.control.Label
        RelativePositionEditField       matlab.ui.control.NumericEditField
        AbsolutePositionEditFieldLabel  matlab.ui.control.Label
        AbsolutePositionEditField       matlab.ui.control.NumericEditField
        mmLabel                         matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        ControlledMovementPanel         matlab.ui.container.Panel
        StrideSizeEditFieldLabel        matlab.ui.control.Label
        StrideSizeEditField             matlab.ui.control.NumericEditField
        mmLabel_3                       matlab.ui.control.Label
        left                            matlab.ui.control.Button
        right                           matlab.ui.control.Button
        CurrentPos                      matlab.ui.control.Table
        ResetstagesButton               matlab.ui.control.Button
        YaxisstagePanel                 matlab.ui.container.Panel
        GlobalmovementPanel_2           matlab.ui.container.Panel
        MoveButton_2                    matlab.ui.control.Button
        MoveModeButtonGroup_2           matlab.ui.container.ButtonGroup
        RelativisticButton_2            matlab.ui.control.RadioButton
        AbsoluteButton_2                matlab.ui.control.RadioButton
        RelativePositionEditField_2Label  matlab.ui.control.Label
        RelativePositionEditField_2     matlab.ui.control.NumericEditField
        AbsolutePositionEditField_2Label  matlab.ui.control.Label
        AbsolutePositionEditField_2     matlab.ui.control.NumericEditField
        mmLabel_4                       matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        ControlledMovementPanel_2       matlab.ui.container.Panel
        StrideSizeEditField_2Label      matlab.ui.control.Label
        StrideSizeEditField_2           matlab.ui.control.NumericEditField
        mmLabel_6                       matlab.ui.control.Label
        Up                              matlab.ui.control.Button
        Down                            matlab.ui.control.Button
        ExportCurrentConfButton         matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        PortNameEditFieldLabel          matlab.ui.control.Label
        PortNameEditField               matlab.ui.control.EditField
        ConnectButton                   matlab.ui.control.Button
        DisconnectButton                matlab.ui.control.Button
        SetAsStartPosButton             matlab.ui.control.Button
        SetAsEndPosButton               matlab.ui.control.Button
        SetStridesButton                matlab.ui.control.Button
        RefreshPosButton                matlab.ui.control.Button
        DontmovestagesduringinitCheckBox  matlab.ui.control.CheckBox
    end


    properties (Access = private)
        connected   % Are the stages connected?
        numOfStages % How many stages were detected
        stages      %Holds the connected stages objects
        initiator   % Description
    end

    properties (Access = public)
        finalData % Desc ription
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, initiator)
            app.connected = false;
            app.numOfStages = 0;
            app.stages = [];
            app.CurrentPos.Data = zeros(4,2);
            app.CurrentPos.RowName = {'Current'; 'Start Pos'; 'End Pos'; 'Stride'};
            if exist('initiator','var')
                app.initiator = initiator;
            else
                app.initiator =[];
            end
            instrreset;
        end

        % Button pushed function: left
        function leftPushed(app, event)
            if (app.connected)
                Zaber_MoveRel(app.stages.Zaber_Devices(1), app.StrideSizeEditField.Value);
                app.CurrentPos.Data(1,1) = Zaber_ReadPosition(app.stages.Zaber_Devices(1));
            else
                errordlg('No stages Detcted. Try connect first','Error');
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if (isempty(app.stages))
                h = waitbar(0,'Please wait');
                data = stagesData(app.PortNameEditField.Value);
                data.dontMoveStages = app.DontmovestagesduringinitCheckBox.Value;
                waitbar(1/3,h);
                [app.stages, ~] = StagesInit(data);
                app.numOfStages = length(app.stages.Zaber_Devices);
                waitbar(2/3,h);
                app.connected = true;
                waitbar(3/3,h);
                close(h)
            else
                errordlg({'You are tired, go to sleep.';'Already Connected!'},'Error');
            end
        end

        % Button pushed function: ResetstagesButton
        function ResetstagesButtonPushed(app, event)
            if (app.connected) 
                Zaber_HomeAll(app.stages.Zaber_Devices);
                app.CurrentPos.Data(1,1) = Zaber_ReadPosition(app.stages.Zaber_Devices(1));
                if(app.numOfStages == 2)
                    app.CurrentPos.Data(1,2) = Zaber_ReadPosition(app.stages.Zaber_Devices(2));
                end
            else
                errordlg('No stages Detcted. Try connect first','Error');
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            error("");
            Zaber_StopAll(app.stages.Zaber_Devices);
        end

        % Button pushed function: right
        function rightButtonPushed(app, event)
            if (app.connected)
                Zaber_MoveRel(app.stages.Zaber_Devices(1), -app.StrideSizeEditField.Value);
                app.CurrentPos.Data(1,1) = Zaber_ReadPosition(app.stages.Zaber_Devices(1));
            else
                errordlg('No stages Detcted. Try connect first','Error');
            end
        end

        % Button pushed function: Up
        function UpPushed(app, event)
            if (app.connected && app.numOfStages==2)
                Zaber_MoveRel(app.stages.Zaber_Devices(2), app.StrideSizeEditField_2.Value);
                app.CurrentPos.Data(1,2) = Zaber_ReadPosition(app.stages.Zaber_Devices(2));
            else
                errordlg('No stages Detcted. Try connect first','Error');
            end
        end

        % Button pushed function: Down
        function DownPushed(app, event)
            if (app.connected && app.numOfStages==2)
                Zaber_MoveRel(app.stages.Zaber_Devices(2), -app.StrideSizeEditField_2.Value);
                app.CurrentPos.Data(1,2) = Zaber_ReadPosition(app.stages.Zaber_Devices(2));
            else
                errordlg('No stages Detcted. Try connect first','Error');
            end
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            if (app.connected)
                if (app.RelativisticButton.Value)
                    Zaber_MoveRel(app.stages.Zaber_Devices(1), app.RelativePositionEditField.Value);
                else
                    Zaber_MoveAbs(app.stages.Zaber_Devices(1), app.AbsolutePositionEditField.Value);
                end
                app.CurrentPos.Data(1,1) = Zaber_ReadPosition(app.stages.Zaber_Devices(1));
            else
                errordlg('No stages Detcted. Try connect first','Error');
            end  
        end

        % Button pushed function: MoveButton_2
        function MoveButton_2Pushed(app, event)
            if (app.connected && app.numOfStages==2)
                if (app.RelativisticButton_2.Value)
                    Zaber_MoveRel(app.stages.Zaber_Devices(2), app.RelativePositionEditField_2.Value);
                else
                    Zaber_MoveAbs(app.stages.Zaber_Devices(2), app.AbsolutePositionEditField_2.Value);
                end
                app.CurrentPos.Data(1,2) = Zaber_ReadPosition(app.stages.Zaber_Devices(2));
            else
                 errordlg('No stages Detcted. Try connect first','Error');
            end  
        end

        % Close request function: StagesControl
        function CloseFcn(app, event)
            delete(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if (app.connected)
                fclose(app.stages.Zaber_Port); 
                delete(app.stages.Zaber_Port);
                app.connected = false;
            end
            if ~isempty(app.initiator)
                stagesFinalData = stagesData(app.PortNameEditField.Value,...
                                app.CurrentPos.Data(2,1), app.CurrentPos.Data(2,2),...
                                app.CurrentPos.Data(3,1), app.CurrentPos.Data(3,2),...
                                app.CurrentPos.Data(4,1), app.CurrentPos.Data(4,2));
                app.initiator.setPositionsFromCalib(stagesFinalData);
            end
            CloseFcn(app, event)
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            if (app.connected)
                fclose(app.stages.Zaber_Port); 
                delete(app.stages.Zaber_Port);
                app.connected = false;
            else
                errordlg({'From what you want to disconnect?'; 'You are not connected!'},'Error')
            end
        end

        % Button pushed function: SetAsStartPosButton
        function SetAsStartPosButtonPushed(app, event)
            app.CurrentPos.Data(2,:) =app.CurrentPos.Data(1,:);
        end

        % Button pushed function: SetAsEndPosButton
        function SetAsEndPosButtonPushed(app, event)
            app.CurrentPos.Data(3,:) =app.CurrentPos.Data(1,:);
        end

        % Button pushed function: SetStridesButton
        function SetStridesButtonPushed(app, event)
            app.CurrentPos.Data(4,1) = app.StrideSizeEditField.Value;
            app.CurrentPos.Data(4,2) = app.StrideSizeEditField_2.Value;
        end

        % Button pushed function: ExportCurrentConfButton
        function ExportCurrentConfButtonPushed(app, event)
            [file,path] = uiputfile('*.mat', 'Save Stages Configurations');
            confs = stagesData(app.PortNameEditField.Value,...
                               app.CurrentPos.Data(2,1), app.CurrentPos.Data(2,2),...
                               app.CurrentPos.Data(3,1), app.CurrentPos.Data(3,2),...
                               app.CurrentPos.Data(4,1), app.CurrentPos.Data(4,2));
            save([path, file], '-struct','confs');
        end

        % Button pushed function: RefreshPosButton
        function RefreshPosButtonPushed(app, event)
            if (app.connected)
                app.CurrentPos.Data(1,1) = Zaber_ReadPosition(app.stages.Zaber_Devices(1));
                if (app.numOfStages == 2)
                    app.CurrentPos.Data(1,2) = Zaber_ReadPosition(app.stages.Zaber_Devices(2));
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StagesControl = uifigure('Visible', 'off');
            app.StagesControl.Name = 'Stages Control and Calibrate Tool';
            app.StagesControl.CloseRequestFcn = createCallbackFcn(app, @CloseFcn, true);
            app.StagesControlAndCalibrateToolLabel = uilabel(app.StagesControl);
            app.StagesControlAndCalibrateToolLabel.Text = 'Stages Control And Calibrate Tool';
            app.XaxisstagePanel = uipanel(app.StagesControl);
            app.XaxisstagePanel.Title = 'X axis stage';
            app.GlobalmovementPanel = uipanel(app.XaxisstagePanel);
            app.GlobalmovementPanel.Title = 'Global movement';
            app.MoveButton = uibutton(app.GlobalmovementPanel, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move!';
            app.MoveModeButtonGroup = uibuttongroup(app.GlobalmovementPanel);
            app.MoveModeButtonGroup.Title = 'Move Mode';
            app.RelativisticButton = uiradiobutton(app.MoveModeButtonGroup);
            app.RelativisticButton.Text = 'Relativistic';
            app.RelativisticButton.Value = true;
            app.AbsoluteButton = uiradiobutton(app.MoveModeButtonGroup);
            app.AbsoluteButton.Text = 'Absolute';
            app.RelativePositionEditFieldLabel = uilabel(app.GlobalmovementPanel);
            app.RelativePositionEditFieldLabel.Text = 'Relative Position';
            app.RelativePositionEditField = uieditfield(app.GlobalmovementPanel, 'numeric');
            app.AbsolutePositionEditFieldLabel = uilabel(app.GlobalmovementPanel);
            app.AbsolutePositionEditFieldLabel.Text = 'Absolute Position';
            app.AbsolutePositionEditField = uieditfield(app.GlobalmovementPanel, 'numeric');
            app.mmLabel = uilabel(app.GlobalmovementPanel);
            app.mmLabel.Text = '[mm]';
            app.mmLabel_2 = uilabel(app.GlobalmovementPanel);
            app.mmLabel_2.Text = '[mm]';
            app.ControlledMovementPanel = uipanel(app.XaxisstagePanel);
            app.ControlledMovementPanel.Title = 'Controlled Movement';
            app.StrideSizeEditFieldLabel = uilabel(app.ControlledMovementPanel);
            app.StrideSizeEditFieldLabel.Text = 'Stride Size';
            app.StrideSizeEditField = uieditfield(app.ControlledMovementPanel, 'numeric');
            app.mmLabel_3 = uilabel(app.ControlledMovementPanel);
            app.mmLabel_3.Text = '[mm]';
            app.left = uibutton(app.ControlledMovementPanel, 'push');
            app.left.ButtonPushedFcn = createCallbackFcn(app, @leftPushed, true);
            app.left.Icon = 'Left.png';
            app.left.Text = '';
            app.right = uibutton(app.ControlledMovementPanel, 'push');
            app.right.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.right.Icon = 'Right.png';
            app.right.Text = '';
            app.CurrentPos = uitable(app.StagesControl);
            app.CurrentPos.ColumnName = {'X axis stage'; 'Y axis stage'};
            app.CurrentPos.RowName = {};
            app.ResetstagesButton = uibutton(app.StagesControl, 'push');
            app.ResetstagesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetstagesButtonPushed, true);
            app.ResetstagesButton.Text = 'Reset stages';
            app.YaxisstagePanel = uipanel(app.StagesControl);
            app.YaxisstagePanel.Title = 'Y axis stage';
            app.GlobalmovementPanel_2 = uipanel(app.YaxisstagePanel);
            app.GlobalmovementPanel_2.Title = 'Global movement';
            app.MoveButton_2 = uibutton(app.GlobalmovementPanel_2, 'push');
            app.MoveButton_2.ButtonPushedFcn = createCallbackFcn(app, @MoveButton_2Pushed, true);
            app.MoveButton_2.Text = 'Move!';
            app.MoveModeButtonGroup_2 = uibuttongroup(app.GlobalmovementPanel_2);
            app.MoveModeButtonGroup_2.Title = 'Move Mode';
            app.RelativisticButton_2 = uiradiobutton(app.MoveModeButtonGroup_2);
            app.RelativisticButton_2.Text = 'Relativistic';
            app.RelativisticButton_2.Value = true;
            app.AbsoluteButton_2 = uiradiobutton(app.MoveModeButtonGroup_2);
            app.AbsoluteButton_2.Text = 'Absolute';
            app.RelativePositionEditField_2Label = uilabel(app.GlobalmovementPanel_2);
            app.RelativePositionEditField_2Label.Text = 'Relative Position';
            app.RelativePositionEditField_2 = uieditfield(app.GlobalmovementPanel_2, 'numeric');
            app.AbsolutePositionEditField_2Label = uilabel(app.GlobalmovementPanel_2);
            app.AbsolutePositionEditField_2Label.Text = 'Absolute Position';
            app.AbsolutePositionEditField_2 = uieditfield(app.GlobalmovementPanel_2, 'numeric');
            app.mmLabel_4 = uilabel(app.GlobalmovementPanel_2);
            app.mmLabel_4.Text = '[mm]';
            app.mmLabel_5 = uilabel(app.GlobalmovementPanel_2);
            app.mmLabel_5.Text = '[mm]';
            app.ControlledMovementPanel_2 = uipanel(app.YaxisstagePanel);
            app.ControlledMovementPanel_2.Title = 'Controlled Movement';
            app.StrideSizeEditField_2Label = uilabel(app.ControlledMovementPanel_2);
            app.StrideSizeEditField_2Label.Text = 'Stride Size';
            app.StrideSizeEditField_2 = uieditfield(app.ControlledMovementPanel_2, 'numeric');
            app.mmLabel_6 = uilabel(app.ControlledMovementPanel_2);
            app.mmLabel_6.Text = '[mm]';
            app.Up = uibutton(app.ControlledMovementPanel_2, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @UpPushed, true);
            app.Up.Icon = 'Up.png';
            app.Up.Text = '';
            app.Down = uibutton(app.ControlledMovementPanel_2, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @DownPushed, true);
            app.Down.Icon = 'Down.png';
            app.Down.Text = '';
            app.ExportCurrentConfButton = uibutton(app.StagesControl, 'push');
            app.ExportCurrentConfButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCurrentConfButtonPushed, true);
            app.ExportCurrentConfButton.Text = 'Export Current Conf';
            app.CloseButton = uibutton(app.StagesControl, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.STOPButton = uibutton(app.StagesControl, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP!';
            app.PortNameEditFieldLabel = uilabel(app.StagesControl);
            app.PortNameEditFieldLabel.Text = 'Port Name:';
            app.PortNameEditField = uieditfield(app.StagesControl, 'text');
            app.PortNameEditField.Value = 'COM3';
            app.ConnectButton = uibutton(app.StagesControl, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.StagesControl, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.SetAsStartPosButton = uibutton(app.StagesControl, 'push');
            app.SetAsStartPosButton.ButtonPushedFcn = createCallbackFcn(app, @SetAsStartPosButtonPushed, true);
            app.SetAsStartPosButton.Text = 'Set As Start Pos';
            app.SetAsEndPosButton = uibutton(app.StagesControl, 'push');
            app.SetAsEndPosButton.ButtonPushedFcn = createCallbackFcn(app, @SetAsEndPosButtonPushed, true);
            app.SetAsEndPosButton.Text = 'Set As End Pos';
            app.SetStridesButton = uibutton(app.StagesControl, 'push');
            app.SetStridesButton.ButtonPushedFcn = createCallbackFcn(app, @SetStridesButtonPushed, true);
            app.SetStridesButton.Text = 'Set Strides';
            app.RefreshPosButton = uibutton(app.StagesControl, 'push');
            app.RefreshPosButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshPosButtonPushed, true);
            app.RefreshPosButton.Text = 'Refresh Pos';
            app.DontmovestagesduringinitCheckBox = uicheckbox(app.StagesControl);
            app.DontmovestagesduringinitCheckBox.Text = 'Don''t move stages during init';
            % Show the figure after all components are created
            app.StagesControl.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stagesCalib(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StagesControl)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StagesControl)
        end
    end
end