classdef stagesGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        HardwarePanel               matlab.ui.container.Panel
        ResetButton                 matlab.ui.control.Button
        ConnectButton               matlab.ui.control.Button
        ImportConfButton            matlab.ui.control.Button
        ExportConfButton            matlab.ui.control.Button
        StagesControlPanelLabel     matlab.ui.control.Label
        ReferenceAllButton          matlab.ui.control.Button
        Stage3Panel                 matlab.ui.container.Panel
        SetLimitsButtonStage2_2     matlab.ui.control.Button
        mmLabelStage3               matlab.ui.control.Label
        LowLimitStage3              matlab.ui.control.NumericEditField
        LowEditFieldLabel_3         matlab.ui.control.Label
        HighLimitStage3             matlab.ui.control.NumericEditField
        HighEditFieldLabel_3        matlab.ui.control.Label
        HomeStage3                  matlab.ui.control.Button
        Stage3SetStepButton         matlab.ui.control.Button
        Stage3SetEndButton          matlab.ui.control.Button
        Stage3SetStartButton        matlab.ui.control.Button
        Stage3StrideEditField       matlab.ui.control.NumericEditField
        StepLabel_3                 matlab.ui.control.Label
        mmLabel_6                   matlab.ui.control.Label
        Stage3Decrease              matlab.ui.control.Button
        Stage3Increase              matlab.ui.control.Button
        Stage3MoveToPositionButton  matlab.ui.control.Button
        mmLabel_5                   matlab.ui.control.Label
        Stage3PositionEditField     matlab.ui.control.NumericEditField
        PosLabel_3                  matlab.ui.control.Label
        Stage2Panel                 matlab.ui.container.Panel
        SetLimitsButtonStage2       matlab.ui.control.Button
        mmLabelStage2               matlab.ui.control.Label
        LowLimitStage2              matlab.ui.control.NumericEditField
        LowEditFieldLabel_2         matlab.ui.control.Label
        HighLimitStage2             matlab.ui.control.NumericEditField
        HighEditFieldLabel_2        matlab.ui.control.Label
        HomeStage2                  matlab.ui.control.Button
        Stage2SetStepButton         matlab.ui.control.Button
        Stage2SetEndButton          matlab.ui.control.Button
        Stage2SetStartButton        matlab.ui.control.Button
        Stage2StrideEditField       matlab.ui.control.NumericEditField
        StepLabel_2                 matlab.ui.control.Label
        mmLabel_4                   matlab.ui.control.Label
        Stage2Decrease              matlab.ui.control.Button
        Stage2Increase              matlab.ui.control.Button
        Stage2MoveToPositionButton  matlab.ui.control.Button
        mmLabel_3                   matlab.ui.control.Label
        Stage2PositionEditField     matlab.ui.control.NumericEditField
        PosLabel_2                  matlab.ui.control.Label
        RefreshPositionButton       matlab.ui.control.Button
        AllStagesHomeButton         matlab.ui.control.Button
        stagesTable                 matlab.ui.control.Table
        Stage1Panel                 matlab.ui.container.Panel
        SetLimitsButtonStage1       matlab.ui.control.Button
        mmLabelStage1               matlab.ui.control.Label
        LowLimitStage1              matlab.ui.control.NumericEditField
        LowEditFieldLabel           matlab.ui.control.Label
        HighLimitStage1             matlab.ui.control.NumericEditField
        HighEditFieldLabel          matlab.ui.control.Label
        HomeStage1                  matlab.ui.control.Button
        Stage1SetStepButton         matlab.ui.control.Button
        Stage1SetEndButton          matlab.ui.control.Button
        Stage1SetStartButton        matlab.ui.control.Button
        Stage1StrideEditField       matlab.ui.control.NumericEditField
        StepLabel                   matlab.ui.control.Label
        mmLabel_2                   matlab.ui.control.Label
        Stage1Decrease              matlab.ui.control.Button
        Stage1Increase              matlab.ui.control.Button
        Stage1MoveToPositionButton  matlab.ui.control.Button
        mmLabel                     matlab.ui.control.Label
        Stage1PositionEditField     matlab.ui.control.NumericEditField
        PosLabel                    matlab.ui.control.Label
        AxisAllocationPanel         matlab.ui.container.Panel
        stage3AxDropDown            matlab.ui.control.DropDown
        stage3AxDropDownLabel       matlab.ui.control.Label
        TestAssignmentButton        matlab.ui.control.Button
        SetStagesGeometryButton     matlab.ui.control.Button
        stage2AxDropDown            matlab.ui.control.DropDown
        stage2AxDropDownLabel       matlab.ui.control.Label
        stage1AxDropDown            matlab.ui.control.DropDown
        stage1AxDropDownLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        stObj
        stData
        extStag
    end
    
    methods (Access = private)
        function updateNotAvailStages(app)
            uiwait(msgbox('NOTICE: No stages Are available'));
            app.stData.numOfStages = 0;
            app.stage1AxDropDown.Items = 'No Stages';
            app.stage1AxDropDown.Value = 'No Stages';
            app.stage2AxDropDown.Items = 'No Stages';
            app.stage2AxDropDown.Value = 'No Stages';
            app.stData.stage1Id = 0;
            app.stData.stage1Ax = 'X';
            app.stData.stage2Id = 0;
            app.stData.stage2Ax = 'Y';
            app.SetStagesGeometryButton.Enable = false;
            app.stagesTable.Data = zeros(4,1);
            app.stagesTable.Enable = false;
        end

        function initGUIComponents(app)
            app.stData.devicesIDs  = app.stObj.getLogicIDs();
            app.stData.numOfStages = app.stObj.vars.numOfStages;
            devicesIdsStr = cell (1,app.stData.numOfStages);
            for i = 1:app.stData.numOfStages
                devicesIdsStr{i} = num2str(app.stData.devicesIDs(i));
            end
            
            axOrder = app.stObj.getAssignment();
            for i = 1:length(axOrder)
                app.stData.axOrder{i} = axOrder(i);
            end
            
            for i = 1:3 
                panelName = sprintf("Stage%dPanel", i);
                dropName  = sprintf("stage%dAxDropDown", i);
                if i <= app.stData.numOfStages
                    app.(panelName).Title = sprintf("Stage %d (%s)", i, app.stData.axOrder{i});
                    
                    for j =1:length(app.(panelName).Children)
                        app.(panelName).Children(j).Enable = true;
                    end
                    
                    app.(dropName).Enable = true;
                    app.(dropName).Items = ['Not Assigned', app.stData.axOrder];
                    app.(dropName).Value = app.stData.axOrder{i};
                else
                    for j =1:length(app.(panelName).Children)
                        app.(panelName).Children(j).Enable = false;
                    end
                    app.(dropName).Enable = false;
                end
            end

            app.stData.assigned = true;
            app.SetStagesGeometryButton.Enable = false;
            app.stagesTable.Data = zeros(4,app.stData.numOfStages);
            app.stagesTable.Data(1,1:app.stData.numOfStages) = app.stObj.getPosition();
        end

        function connectToStages(app)
            if evalin('base', "exist('stgs')"); evalin('base', "clear('stgs')"); end

            fprintf("GUI: Connecting to Stages\n");
            prompt = {'Choose Stages to connect:'; 'Port'};
            dlgtitle = 'Choose Your Weapon';
            dims = [1 35];
            definput = {'Zaber'; 'COM3'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            
            app.stObj = stages(answer{1}, answer{2});
            app.stObj.connect();
            app.stData.avail = app.stObj.hardwareAvailable;
            app.stData.assigned = false;
            
            assignin('base', 'stgs',  app.stObj);
        end

        function refreshPosition(app)
            pos = app.stObj.getPosition();
            app.stagesTable.Data(1,1:app.stData.numOfStages) = pos;
        end
    end
    
    methods (Access = public)
        function hdl = getStgHdl(app)
            hdl = app.stObj;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, stagesIn)
            if nargin == 2 && ~isempty(stagesIn)
                app.stObj = stagesIn;
                app.stData.avail = app.stObj.hardwareAvailable;
                app.stData.assigned = false;
                app.extStag = true;
            else
                app.connectToStages();
            end

            if ~app.stData.avail
                app.updateNotAvailStages();
            else
                app.initGUIComponents();
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
        end

        % Button pushed function: ImportConfButton
        function ImportConfButtonPushed(app, event)
            
        end

        % Button pushed function: ExportConfButton
        function ExportConfButtonPushed(app, event)
            [file,path] = uiputfile('*.mat', 'Save Stages Configurations');

            confs.stage1Ax = app.stData.stage1Ax;
            confs.stage2Ax = app.stData.stage2Ax;
            
            confs.startPos1 = app.stagesTable.Data(2,1);
            confs.startPos2 = app.stagesTable.Data(2,2);
            confs.endPos1   = app.stagesTable.Data(3,1);
            confs.endPos2   = app.stagesTable.Data(3,2);
            confs.stride1   = app.stagesTable.Data(4,1);
            confs.stride2   = app.stagesTable.Data(4,2);

            save([path, file], '-struct','confs');
        end

        % Button pushed function: RefreshPositionButton
        function RefreshPositionButtonPushed(app, event)
            app.refreshPosition();
        end

        % Button pushed function: AllStagesHomeButton
        function AllStagesHomeButtonPushed(app, event)
            app.stObj.allHome();
        end

        % Button pushed function: ReferenceAllButton
        function ReferenceAllButtonPushed(app, event)
            app.stObj.referenceAll();
        end

        % Button pushed function: TestAssignmentButton
        function TestAssignmentButtonPushed(app, event)
            for i = 1:app.stData.numOfStages
                dropName = sprintf("stage%dAxDropDown", i);
                ax = app.(dropName).Value;
                if strcmp(ax, 'Not Assigned')
                    app.TestAssignmentButton.Text = sprintf("Stage %s is N/A.", ax);
                    pause(1);
                else
                    app.TestAssignmentButton.Text = sprintf("Stage %s is moving.", ax);
                    app.stObj.moveStagesForIdentification(i);
                end
            end
            app.TestAssignmentButton.Text = "Test Assignment";
        end

        % Button pushed function: SetStagesGeometryButton
        function SetStagesGeometryButtonPushed(app, event)
            ax = [];
            for i = 1:app.stData.numOfStages
                dropName = sprintf("stage%dAxDropDown", i);
                ax = [ax, app.(dropName).Value];
            end

            if length(unique(ax)) < app.stData.numOfStages
                msgbox('Check Assignment. 2 Axes allocated to the same stage.');
                return;
            end
            
            app.stObj.assignStagesAxes(ax);
            
            axOrder = app.stObj.getAssignment();
            for i = 1:length(axOrder)
                app.stData.axOrder{i} = axOrder(i);
            end

            for i=1:app.stData.numOfStages
                panelName = sprintf("Stage%dPanel", i);
                app.(panelName).Title = sprintf("Stage %d (%s)", i, app.stData.axOrder{i});
            end
            
            app.stData.assigned = true;
        end

        % Button pushed function: Stage1MoveToPositionButton
        function Stage1MoveToPositionButtonPushed(app, event)
            pos = app.Stage1PositionEditField.Value;
            app.stObj.moveAbsId(1, pos);
            pos = app.stObj.getPosition();
            app.stagesTable.Data(1,1:app.stData.numOfStages) = pos;
        end

        % Button pushed function: Stage2MoveToPositionButton
        function Stage2MoveToPositionButtonPushed(app, event)
            pos = app.Stage2PositionEditField.Value;
            app.stObj.moveAbsId(2, pos);
            pos = app.stObj.getPosition();
            app.stagesTable.Data(1,1:app.stData.numOfStages) = pos;
        end

        % Button pushed function: Stage3MoveToPositionButton
        function Stage3MoveToPositionButtonPushed(app, event)
            pos = app.Stage3PositionEditField.Value;
            app.stObj.moveAbsId(3, pos);
            pos = app.stObj.getPosition();
            app.stagesTable.Data(1,1:app.stData.numOfStages) = pos;
        end

        % Button pushed function: Stage1Increase
        function Stage1IncreaseButtonPushed(app, event)
            step = app.Stage1StrideEditField.Value;
            app.stObj.moveRelId(1, step)
            pos = app.stObj.getPosition();
            app.stagesTable.Data(1,1:app.stData.numOfStages) = pos;
        end

        % Button pushed function: Stage2Increase
        function Stage2IncreaseButtonPushed(app, event)
            step = app.Stage2StrideEditField.Value;
            app.stObj.moveRelId(2, step)
            pos = app.stObj.getPosition();
            app.stagesTable.Data(1,1:app.stData.numOfStages) = pos;
        end

        % Button pushed function: Stage3Increase
        function Stage3IncreaseButtonPushed(app, event)
            step = app.Stage3StrideEditField.Value;
            app.stObj.moveRelId(3, step)
            pos = app.stObj.getPosition();
            app.stagesTable.Data(1,1:app.stData.numOfStages) = pos; 
        end

        % Button pushed function: Stage1Decrease
        function Stage1DecreaseButtonPushed(app, event)
            step = app.Stage1StrideEditField.Value;
            app.stObj.moveRelId(1, -step)
            pos = app.stObj.getPosition();
            app.stagesTable.Data(1,1:app.stData.numOfStages) = pos;
        end

        % Button pushed function: Stage2Decrease
        function Stage2DecreaseButtonPushed(app, event)
            step = app.Stage2StrideEditField.Value;
            app.stObj.moveRelId(2, -step)
            pos = app.stObj.getPosition();
            app.stagesTable.Data(1,1:app.stData.numOfStages) = pos;
        end

        % Button pushed function: Stage3Decrease
        function Stage3DecreaseButtonPushed(app, event)
            step = app.Stage3StrideEditField.Value;
            app.stObj.moveRelId(3, -step)
            pos = app.stObj.getPosition();
            app.stagesTable.Data(1,1:app.stData.numOfStages) = pos;
        end

        % Button pushed function: Stage1SetStartButton
        function Stage1SetStartButtonPushed(app, event)
            pos = app.stObj.getPosition();
            app.stagesTable.Data(2,1) = pos(1);
        end

        % Button pushed function: Stage2SetStartButton
        function Stage2SetStartButtonPushed(app, event)
            pos = app.stObj.getPosition();
            app.stagesTable.Data(2,2) = pos(2);
        end

        % Button pushed function: Stage3SetStartButton
        function Stage3SetStartButtonPushed(app, event)
            pos = app.stObj.getPosition();
            app.stagesTable.Data(2,3) = pos(3);
        end

        % Button pushed function: Stage1SetStepButton
        function Stage1SetStepButtonPushed(app, event)
            app.stagesTable.Data(3,1) = app.Stage1StrideEditField.Value;
        end

        % Button pushed function: Stage2SetStepButton
        function Stage2SetStepButtonPushed(app, event)
            app.stagesTable.Data(3,2) = app.Stage2StrideEditField.Value;
        end

        % Button pushed function: Stage3SetStepButton
        function Stage3SetStepButtonPushed(app, event)
            app.stagesTable.Data(3,3) = app.Stage3StrideEditField.Value;
        end

        % Button pushed function: Stage1SetEndButton
        function Stage1SetEndButtonPushed(app, event)
            pos = app.stObj.getPosition();
            app.stagesTable.Data(4,1) = pos(1);
        end

        % Button pushed function: Stage2SetEndButton
        function Stage2SetEndButtonPushed(app, event)
            pos = app.stObj.getPosition();
            app.stagesTable.Data(4,2) = pos(2);
        end

        % Button pushed function: Stage3SetEndButton
        function Stage3SetEndButtonPushed(app, event)
            pos = app.stObj.getPosition();
            app.stagesTable.Data(4,3) = pos(3);
        end

        % Button pushed function: HomeStage1
        function HomeStage1ButtonPushed(app, event)
             app.stObj.home(1);
        end

        % Button pushed function: HomeStage2
        function HomeStage2ButtonPushed(app, event)
             app.stObj.home(2);
        end

        % Button pushed function: HomeStage3
        function HomeStage3ButtonPushed(app, event)
             app.stObj.home(3);
        end

        % Button pushed function: SetLimitsButtonStage1
        function SetLimitsButtonStage1Pushed(app, event)
            app.stObj.setLimits(app.stData.axOrder{1}, app.HighLimitStage1.Value, app.LowLimitStage1.Value);
        end

        % Button pushed function: SetLimitsButtonStage2
        function SetLimitsButtonStage2Pushed(app, event)
            app.stObj.setLimits(app.stData.axOrder{2}, app.HighLimitStage2.Value, app.LowLimitStage2.Value);
        end

        % Button pushed function: SetLimitsButtonStage2_2
        function SetLimitsButtonStage2_2Pushed(app, event)
            app.stObj.setLimits(app.stData.axOrder{3}, app.HighLimitStage3.Value, app.LowLimitStage3.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AxisAllocationPanel = uipanel(app.UIFigure);
            app.AxisAllocationPanel.Title = 'Axis Allocation';
            app.stage1AxDropDownLabel = uilabel(app.AxisAllocationPanel);
            app.stage1AxDropDownLabel.Text = 'stage 1 Ax';
            app.stage1AxDropDown = uidropdown(app.AxisAllocationPanel);
            app.stage1AxDropDown.Items = {'X', 'Y', 'Z'};
            app.stage1AxDropDown.Value = 'X';
            app.stage2AxDropDownLabel = uilabel(app.AxisAllocationPanel);
            app.stage2AxDropDownLabel.Text = 'stage 2 Ax';
            app.stage2AxDropDown = uidropdown(app.AxisAllocationPanel);
            app.stage2AxDropDown.Items = {'X', 'Y', 'Z'};
            app.stage2AxDropDown.Value = 'Y';
            app.SetStagesGeometryButton = uibutton(app.AxisAllocationPanel, 'push');
            app.SetStagesGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @SetStagesGeometryButtonPushed, true);
            app.SetStagesGeometryButton.Text = {'Set Stages'; 'Geometry'};
            app.TestAssignmentButton = uibutton(app.AxisAllocationPanel, 'push');
            app.TestAssignmentButton.ButtonPushedFcn = createCallbackFcn(app, @TestAssignmentButtonPushed, true);
            app.TestAssignmentButton.Text = 'Test Assignment';
            app.stage3AxDropDownLabel = uilabel(app.AxisAllocationPanel);
            app.stage3AxDropDownLabel.Text = 'stage 3 Ax';
            app.stage3AxDropDown = uidropdown(app.AxisAllocationPanel);
            app.stage3AxDropDown.Items = {'X', 'Y', 'Z'};
            app.stage3AxDropDown.Value = 'Z';
            app.Stage1Panel = uipanel(app.UIFigure);
            app.Stage1Panel.Title = 'Stage 1 (X)';
            app.PosLabel = uilabel(app.Stage1Panel);
            app.PosLabel.Text = 'Pos:';
            app.Stage1PositionEditField = uieditfield(app.Stage1Panel, 'numeric');
            app.mmLabel = uilabel(app.Stage1Panel);
            app.mmLabel.Text = '[mm]';
            app.Stage1MoveToPositionButton = uibutton(app.Stage1Panel, 'push');
            app.Stage1MoveToPositionButton.ButtonPushedFcn = createCallbackFcn(app, @Stage1MoveToPositionButtonPushed, true);
            app.Stage1MoveToPositionButton.Text = 'Move To Position';
            app.Stage1Increase = uibutton(app.Stage1Panel, 'push');
            app.Stage1Increase.ButtonPushedFcn = createCallbackFcn(app, @Stage1IncreaseButtonPushed, true);
            app.Stage1Increase.Text = '+';
            app.Stage1Decrease = uibutton(app.Stage1Panel, 'push');
            app.Stage1Decrease.ButtonPushedFcn = createCallbackFcn(app, @Stage1DecreaseButtonPushed, true);
            app.Stage1Decrease.Text = '-';
            app.mmLabel_2 = uilabel(app.Stage1Panel);
            app.mmLabel_2.Text = '[mm]';
            app.StepLabel = uilabel(app.Stage1Panel);
            app.StepLabel.Text = 'Step:';
            app.Stage1StrideEditField = uieditfield(app.Stage1Panel, 'numeric');
            app.Stage1StrideEditField.Value = 1;
            app.Stage1SetStartButton = uibutton(app.Stage1Panel, 'push');
            app.Stage1SetStartButton.ButtonPushedFcn = createCallbackFcn(app, @Stage1SetStartButtonPushed, true);
            app.Stage1SetStartButton.Text = 'Set Start';
            app.Stage1SetEndButton = uibutton(app.Stage1Panel, 'push');
            app.Stage1SetEndButton.ButtonPushedFcn = createCallbackFcn(app, @Stage1SetEndButtonPushed, true);
            app.Stage1SetEndButton.Text = 'Set End';
            app.Stage1SetStepButton = uibutton(app.Stage1Panel, 'push');
            app.Stage1SetStepButton.ButtonPushedFcn = createCallbackFcn(app, @Stage1SetStepButtonPushed, true);
            app.Stage1SetStepButton.Text = 'Set Step';
            app.HomeStage1 = uibutton(app.Stage1Panel, 'push');
            app.HomeStage1.ButtonPushedFcn = createCallbackFcn(app, @HomeStage1ButtonPushed, true);
            app.HomeStage1.Text = 'Home';
            app.HighEditFieldLabel = uilabel(app.Stage1Panel);
            app.HighEditFieldLabel.Text = 'High';
            app.HighLimitStage1 = uieditfield(app.Stage1Panel, 'numeric');
            app.HighLimitStage1.Value = 150;
            app.LowEditFieldLabel = uilabel(app.Stage1Panel);
            app.LowEditFieldLabel.Text = 'Low';
            app.LowLimitStage1 = uieditfield(app.Stage1Panel, 'numeric');
            app.mmLabelStage1 = uilabel(app.Stage1Panel);
            app.mmLabelStage1.Text = '[mm]';
            app.SetLimitsButtonStage1 = uibutton(app.Stage1Panel, 'push');
            app.SetLimitsButtonStage1.ButtonPushedFcn = createCallbackFcn(app, @SetLimitsButtonStage1Pushed, true);
            app.SetLimitsButtonStage1.Text = 'Set Limits';
            app.stagesTable = uitable(app.UIFigure);
            app.stagesTable.ColumnName = {'Stage 1'; 'Stage 2'; 'Stage 3'};
            app.stagesTable.RowName = {'Position'; 'Start'; 'Stride'; 'End'};
            app.AllStagesHomeButton = uibutton(app.UIFigure, 'push');
            app.AllStagesHomeButton.ButtonPushedFcn = createCallbackFcn(app, @AllStagesHomeButtonPushed, true);
            app.AllStagesHomeButton.Text = 'All Stages Home';
            app.RefreshPositionButton = uibutton(app.UIFigure, 'push');
            app.RefreshPositionButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshPositionButtonPushed, true);
            app.RefreshPositionButton.Text = 'Refresh Position';
            app.Stage2Panel = uipanel(app.UIFigure);
            app.Stage2Panel.Title = 'Stage 2 (Y)';
            app.PosLabel_2 = uilabel(app.Stage2Panel);
            app.PosLabel_2.Text = 'Pos:';
            app.Stage2PositionEditField = uieditfield(app.Stage2Panel, 'numeric');
            app.mmLabel_3 = uilabel(app.Stage2Panel);
            app.mmLabel_3.Text = '[mm]';
            app.Stage2MoveToPositionButton = uibutton(app.Stage2Panel, 'push');
            app.Stage2MoveToPositionButton.ButtonPushedFcn = createCallbackFcn(app, @Stage2MoveToPositionButtonPushed, true);
            app.Stage2MoveToPositionButton.Text = 'Move To Position';
            app.Stage2Increase = uibutton(app.Stage2Panel, 'push');
            app.Stage2Increase.ButtonPushedFcn = createCallbackFcn(app, @Stage2IncreaseButtonPushed, true);
            app.Stage2Increase.Text = '+';
            app.Stage2Decrease = uibutton(app.Stage2Panel, 'push');
            app.Stage2Decrease.ButtonPushedFcn = createCallbackFcn(app, @Stage2DecreaseButtonPushed, true);
            app.Stage2Decrease.Text = '-';
            app.mmLabel_4 = uilabel(app.Stage2Panel);
            app.mmLabel_4.Text = '[mm]';
            app.StepLabel_2 = uilabel(app.Stage2Panel);
            app.StepLabel_2.Text = 'Step:';
            app.Stage2StrideEditField = uieditfield(app.Stage2Panel, 'numeric');
            app.Stage2StrideEditField.Value = 1;
            app.Stage2SetStartButton = uibutton(app.Stage2Panel, 'push');
            app.Stage2SetStartButton.ButtonPushedFcn = createCallbackFcn(app, @Stage2SetStartButtonPushed, true);
            app.Stage2SetStartButton.Text = 'Set Start';
            app.Stage2SetEndButton = uibutton(app.Stage2Panel, 'push');
            app.Stage2SetEndButton.ButtonPushedFcn = createCallbackFcn(app, @Stage2SetEndButtonPushed, true);
            app.Stage2SetEndButton.Text = 'Set End';
            app.Stage2SetStepButton = uibutton(app.Stage2Panel, 'push');
            app.Stage2SetStepButton.ButtonPushedFcn = createCallbackFcn(app, @Stage2SetStepButtonPushed, true);
            app.Stage2SetStepButton.Text = 'Set Step';
            app.HomeStage2 = uibutton(app.Stage2Panel, 'push');
            app.HomeStage2.ButtonPushedFcn = createCallbackFcn(app, @HomeStage2ButtonPushed, true);
            app.HomeStage2.Text = 'Home';
            app.HighEditFieldLabel_2 = uilabel(app.Stage2Panel);
            app.HighEditFieldLabel_2.Text = 'High';
            app.HighLimitStage2 = uieditfield(app.Stage2Panel, 'numeric');
            app.HighLimitStage2.Value = 150;
            app.LowEditFieldLabel_2 = uilabel(app.Stage2Panel);
            app.LowEditFieldLabel_2.Text = 'Low';
            app.LowLimitStage2 = uieditfield(app.Stage2Panel, 'numeric');
            app.mmLabelStage2 = uilabel(app.Stage2Panel);
            app.mmLabelStage2.Text = '[mm]';
            app.SetLimitsButtonStage2 = uibutton(app.Stage2Panel, 'push');
            app.SetLimitsButtonStage2.ButtonPushedFcn = createCallbackFcn(app, @SetLimitsButtonStage2Pushed, true);
            app.SetLimitsButtonStage2.Text = 'Set Limits';
            app.Stage3Panel = uipanel(app.UIFigure);
            app.Stage3Panel.Title = 'Stage 3 (Z)';
            app.PosLabel_3 = uilabel(app.Stage3Panel);
            app.PosLabel_3.Text = 'Pos:';
            app.Stage3PositionEditField = uieditfield(app.Stage3Panel, 'numeric');
            app.mmLabel_5 = uilabel(app.Stage3Panel);
            app.mmLabel_5.Text = '[mm]';
            app.Stage3MoveToPositionButton = uibutton(app.Stage3Panel, 'push');
            app.Stage3MoveToPositionButton.ButtonPushedFcn = createCallbackFcn(app, @Stage3MoveToPositionButtonPushed, true);
            app.Stage3MoveToPositionButton.Text = 'Move To Position';
            app.Stage3Increase = uibutton(app.Stage3Panel, 'push');
            app.Stage3Increase.ButtonPushedFcn = createCallbackFcn(app, @Stage3IncreaseButtonPushed, true);
            app.Stage3Increase.Text = '+';
            app.Stage3Decrease = uibutton(app.Stage3Panel, 'push');
            app.Stage3Decrease.ButtonPushedFcn = createCallbackFcn(app, @Stage3DecreaseButtonPushed, true);
            app.Stage3Decrease.Text = '-';
            app.mmLabel_6 = uilabel(app.Stage3Panel);
            app.mmLabel_6.Text = '[mm]';
            app.StepLabel_3 = uilabel(app.Stage3Panel);
            app.StepLabel_3.Text = 'Step:';
            app.Stage3StrideEditField = uieditfield(app.Stage3Panel, 'numeric');
            app.Stage3StrideEditField.Value = 1;
            app.Stage3SetStartButton = uibutton(app.Stage3Panel, 'push');
            app.Stage3SetStartButton.ButtonPushedFcn = createCallbackFcn(app, @Stage3SetStartButtonPushed, true);
            app.Stage3SetStartButton.Text = 'Set Start';
            app.Stage3SetEndButton = uibutton(app.Stage3Panel, 'push');
            app.Stage3SetEndButton.ButtonPushedFcn = createCallbackFcn(app, @Stage3SetEndButtonPushed, true);
            app.Stage3SetEndButton.Text = 'Set End';
            app.Stage3SetStepButton = uibutton(app.Stage3Panel, 'push');
            app.Stage3SetStepButton.ButtonPushedFcn = createCallbackFcn(app, @Stage3SetStepButtonPushed, true);
            app.Stage3SetStepButton.Text = 'Set Step';
            app.HomeStage3 = uibutton(app.Stage3Panel, 'push');
            app.HomeStage3.ButtonPushedFcn = createCallbackFcn(app, @HomeStage3ButtonPushed, true);
            app.HomeStage3.Text = 'Home';
            app.HighEditFieldLabel_3 = uilabel(app.Stage3Panel);
            app.HighEditFieldLabel_3.Text = 'High';
            app.HighLimitStage3 = uieditfield(app.Stage3Panel, 'numeric');
            app.HighLimitStage3.Value = 150;
            app.LowEditFieldLabel_3 = uilabel(app.Stage3Panel);
            app.LowEditFieldLabel_3.Text = 'Low';
            app.LowLimitStage3 = uieditfield(app.Stage3Panel, 'numeric');
            app.mmLabelStage3 = uilabel(app.Stage3Panel);
            app.mmLabelStage3.Text = '[mm]';
            app.SetLimitsButtonStage2_2 = uibutton(app.Stage3Panel, 'push');
            app.SetLimitsButtonStage2_2.ButtonPushedFcn = createCallbackFcn(app, @SetLimitsButtonStage2_2Pushed, true);
            app.SetLimitsButtonStage2_2.Text = 'Set Limits';
            app.ReferenceAllButton = uibutton(app.UIFigure, 'push');
            app.ReferenceAllButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceAllButtonPushed, true);
            app.ReferenceAllButton.Text = 'Reference All';
            app.StagesControlPanelLabel = uilabel(app.UIFigure);
            app.StagesControlPanelLabel.Text = 'Stages Control Panel';
            app.HardwarePanel = uipanel(app.UIFigure);
            app.HardwarePanel.Title = 'Hardware';
            app.ExportConfButton = uibutton(app.HardwarePanel, 'push');
            app.ExportConfButton.ButtonPushedFcn = createCallbackFcn(app, @ExportConfButtonPushed, true);
            app.ExportConfButton.Text = 'Export Conf.';
            app.ImportConfButton = uibutton(app.HardwarePanel, 'push');
            app.ImportConfButton.ButtonPushedFcn = createCallbackFcn(app, @ImportConfButtonPushed, true);
            app.ImportConfButton.Text = 'Import Conf.';
            app.ConnectButton = uibutton(app.HardwarePanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ResetButton = uibutton(app.HardwarePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stagesGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end